classdef UI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        UnmannedTab                matlab.ui.container.Tab
        ConnecttoRPiButton         matlab.ui.control.Button
        USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextArea  matlab.ui.control.TextArea
        USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextAreaLabel  matlab.ui.control.Label
        RESTButton                 matlab.ui.control.Button
        USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextArea  matlab.ui.control.TextArea
        USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextAreaLabel  matlab.ui.control.Label
        VideoModeDropDown          matlab.ui.control.DropDown
        VideoModeDropDownLabel     matlab.ui.control.Label
        InstructionsDropDown       matlab.ui.control.DropDown
        InstructionsDropDownLabel  matlab.ui.control.Label
        MannedModeTab              matlab.ui.container.Tab
        STOPButton                 matlab.ui.control.Button
        STARTMANUALMODEButton      matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        BackwardButton             matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam; % Description
        man; % Description
        rpi; % Description
    end
    
   methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            manual(app.rpi, 4);
            pause(5);
            manual(app.rpi, 0);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            manual(app.rpi, 1);
            pause(3)
            manual(app.rpi, 0);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            manual(app.rpi, 2);
            manual(app.rpi, 0);
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            manual(app.rpi, 3);
            manual(app.rpi, 0);
        end

        % Value changed function: InstructionsDropDown
        function InstructionsDropDownValueChanged(app, event)
            value = app.InstructionsDropDown.Value;
            type = value;
            switch type
                case "Traveller"
                    main1(app.rpi,5);
                case "Scanning"
                    main1(app.rpi,6);
                case "Follow Me"
                    main1(app.rpi,7);
                case "Fire Fighter"
                    main1(app.rpi,8);
                case "Return to Home"
                    main1(app.rpi,9);
                case "Manual"
                    main1(app.rpi,10);                 
                case "Rest"
                    main1(app.rpi,11);
                otherwise 
                    main1(app.rpi,11);
            end
        end

        % Callback function
        function UnmannedModeCheckBoxValueChanged(app, event)
            value = app.UnmannedModeCheckBox.Value;
            if value==1
                app.man=0;
            end
            
        end

        % Value changed function: VideoModeDropDown
        function VideoModeDropDownValueChanged(app, event)
            value = app.VideoModeDropDown.Value;
            type = value;
            switch type
                case "Animal ID"
                    imagemode(app.rpi, app.cam, 0);
                case "Follow Me"
                    imagemode(app.rpi, app.cam, 1);
                case "Object ID"
                    imagemode(app.rpi, app.cam, 2);
                case "Fire Fighter"
                    imagemode(app.rpi, app.cam, 3);
                case "Rest"
                    imagemode(app.rpi, app.cam, 3);
                otherwise 
                    imagemode(app.rpi, app.cam, 3);
            end
        end

        % Size changed function: MannedModeTab
        function MannedModeTabSizeChanged(app, event)
            position = app.MannedModeTab.Position;
        end

        % Button pushed function: STARTMANUALMODEButton
        function STARTMANUALMODEButtonPushed(app, event)
                    app.man=1;
                    while app.man
                        frame=snapshot(app.cam);
                        imshow(frame, 'Parent',app.UIAxes);
                        drawnow;
                    end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.man=0;
            manual(app.rpi, 0);
            clear app.cam;
            clear app.rpi;
        end

        % Button pushed function: RESTButton
        function RESTButtonPushed(app, event)
            main1(app.rpi,11);
        end

        % Button pushed function: ConnecttoRPiButton
        function ConnecttoRPiButtonPushed(app, event)
            app.rpi = raspi();
%             raspiObj =app.rpi
            app.cam = webcam(app.rpi,'mmal service 16.1 (platform:bcm2835-v4l2)','640x480');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UnmannedTab = uitab(app.TabGroup);
            app.UnmannedTab.Title = 'Unmanned';
            app.InstructionsDropDownLabel = uilabel(app.UnmannedTab);
            app.InstructionsDropDownLabel.Text = 'Instructions';
            app.InstructionsDropDown = uidropdown(app.UnmannedTab);
            app.InstructionsDropDown.Items = {'Idle', 'Traveller', 'Scanning', 'Follow Me', 'Fire Fighter', 'Return to Home', 'Manual', 'Rest'};
            app.InstructionsDropDown.ValueChangedFcn = createCallbackFcn(app, @InstructionsDropDownValueChanged, true);
            app.InstructionsDropDown.Value = 'Idle';
            app.VideoModeDropDownLabel = uilabel(app.UnmannedTab);
            app.VideoModeDropDownLabel.Text = 'Video Mode';
            app.VideoModeDropDown = uidropdown(app.UnmannedTab);
            app.VideoModeDropDown.Items = {'Idle', 'Animal ID', 'Object ID', 'Face Detection', 'Follow Me', 'Fire Fighter', 'Manual', 'Rest'};
            app.VideoModeDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoModeDropDownValueChanged, true);
            app.VideoModeDropDown.Value = 'Idle';
            app.USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextAreaLabel = uilabel(app.UnmannedTab);
            app.USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextAreaLabel.Text = 'USE KEYBOARD INTERRUPTS BEFORE CHANGING VIDEO MODES';
            app.USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextArea = uitextarea(app.UnmannedTab);
            app.USEKEYBOARDINTERRUPTSBEFORECHANGINGVIDEOMODESTextArea.Value = {'ctrl + c'};
            app.RESTButton = uibutton(app.UnmannedTab, 'push');
            app.RESTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTButtonPushed, true);
            app.RESTButton.Text = 'REST';
            app.USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextAreaLabel = uilabel(app.UnmannedTab);
            app.USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextAreaLabel.Text = 'USE REST BUTTON BEFORE CHANGING INSTRUCTIONS ';
            app.USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextArea = uitextarea(app.UnmannedTab);
            app.USERESTBUTTONBEFORECHANGINGINSTRUCTIONSTextArea.Value = {'REST'};
            app.ConnecttoRPiButton = uibutton(app.UnmannedTab, 'push');
            app.ConnecttoRPiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoRPiButtonPushed, true);
            app.ConnecttoRPiButton.Text = 'Connect to RPi';
            app.MannedModeTab = uitab(app.TabGroup);
            app.MannedModeTab.SizeChangedFcn = createCallbackFcn(app, @MannedModeTabSizeChanged, true);
            app.MannedModeTab.Title = 'Manned Mode';
            app.UIAxes = uiaxes(app.MannedModeTab);
            title(app.UIAxes, 'Image Feed')
            zlabel(app.UIAxes, 'Z')
            app.ForwardButton = uibutton(app.MannedModeTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.MannedModeTab, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.LeftButton = uibutton(app.MannedModeTab, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.MannedModeTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.STARTMANUALMODEButton = uibutton(app.MannedModeTab, 'push');
            app.STARTMANUALMODEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTMANUALMODEButtonPushed, true);
            app.STARTMANUALMODEButton.Text = 'START MANUAL MODE';
            app.STOPButton = uibutton(app.MannedModeTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end